classdef Commandd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ADSTestBenchGUILabel           matlab.ui.control.Label
        Velocity_Lead_VehiclemsEditField  matlab.ui.control.NumericEditField
        Velocity_Lead_VehiclemsEditFieldLabel  matlab.ui.control.Label
        Velocity_right_lane_maxmsEditField  matlab.ui.control.NumericEditField
        Velocity_right_lane_maxmsEditFieldLabel  matlab.ui.control.Label
        Velocity_left_lane_maxmsEditField  matlab.ui.control.NumericEditField
        Velocity_left_lane_maxmsEditFieldLabel  matlab.ui.control.Label
        Ego_Vehicle_ACC_Velocity_setmsEditField  matlab.ui.control.NumericEditField
        Ego_Vehicle_ACC_Velocity_setmsEditFieldLabel  matlab.ui.control.Label
        Ego_Vehicle_lateralposition_egomEditField  matlab.ui.control.NumericEditField
        Ego_Vehicle_lateralposition_egomEditFieldLabel  matlab.ui.control.Label
        EgoVehicleVelocitymsEditField  matlab.ui.control.NumericEditField
        EgoVehicleVelocitymsEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CommandButtonGroupSelectionChanged(app, event)
        if app.autonomousButton.Value == false   
            if app.SwitchLeftButton.Value == true
                    set_param('Studienarbeit/command_manual','value',num2str(1)); 
                    %app.SwitchLeftButton.Value = false;
%                     str = app.commandseriesTextArea.Value;
%                     str{end+1}='switch left';
%                     app.commandseriesTextArea.Value = str;
                    
                    
            elseif app.SwitchRightButton.Value == true
                    set_param('Studienarbeit/command_manual','value',num2str(2));
                    %app.SwitchRightButton.Value = false;
%                     str = app.commandseriesTextArea.Value;
%                     str{end+1}='switch right';
%                     app.commandseriesTextArea.Value = str;
                    
        elseif app.UTurnLeftButton.Value == true
            set_param('Studienarbeit/command_manual','value',num2str(3));
            end
            
            pause(0.5);
%             str = app.commandseriesTextArea.Value;
%             str{end+1}='go straight';
%             app.commandseriesTextArea.Value = str;
            app.LaneKeepingButton.Value = true;
            set_param('Studienarbeit/command_manual','value',num2str(0));
    elseif app.autonomousButton.Value == true
            set_param("Studienarbeit/manual_or_autonomous",'value',num2str(0));
            app.LaneKeepingButton.Value = true;
    end
           
        end

        % Callback function
        function RuntheSetUpButtonValueChanged(app, event)
            value = app.RuntheSetUpButton.Value;
            if value == true
                instruction;
            end
        end

        % Value changed function: EgoVehicleVelocitymsEditField
        function EgoVehicleVelocitymsEditFieldValueChanged(app, event)
            value = app.EgoVehicleVelocitymsEditField.Value;
            assignin('base','v0_ego' , value);
        end

        % Callback function
        function Ego_Vehicle_longitudinalpositionmEditFieldValueChanged(app, event)
            value = app.Ego_Vehicle_longitudinalpositionmEditField.Value;
            assignin('base','x0_ego' , value);
        end

        % Value changed function: Ego_Vehicle_lateralposition_egomEditField
        function Ego_Vehicle_lateralposition_egomEditFieldValueChanged(app, event)
            value = app.Ego_Vehicle_lateralposition_egomEditField.Value;
            assignin('base','y0_ego' , value);
            if value>0
                assignin('base','initial_lane' , 1);
            elseif value<0
                assignin('base','initial_lane' , 0);
            end
        end

        % Value changed function: Ego_Vehicle_ACC_Velocity_setmsEditField
        function Ego_Vehicle_ACC_Velocity_setmsEditFieldValueChanged(app, event)
            value = app.Ego_Vehicle_ACC_Velocity_setmsEditField.Value;
            assignin('base','v_set' , value);
        end

        % Value changed function: Velocity_left_lane_maxmsEditField
        function Velocity_left_lane_maxmsEditFieldValueChanged(app, event)
            value = app.Velocity_left_lane_maxmsEditField.Value;
            assignin('base','velocity_left_lane_max' , value);
        end

        % Value changed function: Velocity_right_lane_maxmsEditField
        function Velocity_right_lane_maxmsEditFieldValueChanged(app, event)
            value = app.Velocity_right_lane_maxmsEditField.Value;
            assignin('base','velocity_right_lane_max' , value);
        end

        % Callback function
        function canswitchlaneButtonPushed(app, event)
%             initial_velocity_ego = evalin('base', 'Cf');
%             velocity_lane_max_1 = app.Velocity_left_lane_maxmsEditField.Value;
%             velocity_lane_max_2 = app.Velocity_right_lane_maxmsEditField.Value;
%             velocity_vehicle_B =10;
%             velocity_ego_target_1=max(min(velocity_vehicle_B + 20*1000/3600, velocity_lane_max_1), initial_velocity_ego); % 20 km/h->200/36 m/s,
%             velocity_ego_target_2=max(min(velocity_vehicle_B + 20*1000/3600, velocity_lane_max_2), initial_velocity_ego);
%             distance_between_ego_B_initial = 20;
%             lane_width = 3.6;
%             aymax = 4;
%             aymin = -4;
%             max_ac = 2;
%             safety_distance=3;
%             Tmin1 = max(sqrt(5.77*lane_width/aymax), sqrt(-5.77*lane_width/aymin));
%             Tmin2_1 = (velocity_ego_target_1 - initial_velocity_ego)/(2/3*max_ac);
%             Tmin2_2 = (velocity_ego_target_2 - initial_velocity_ego)/(2/3*max_ac);
%             duration_1 = 2*(distance_between_ego_B_initial - safety_distance )/(velocity_ego_target_1 + initial_velocity_ego - 2*velocity_vehicle_B);
%             can_overtake_1 = max(Tmin1,Tmin2_1) < duration_1;
%             if can_overtake_1 == 1
%                 app.switch_left_yesButton.Value=true;
%             elseif can_overtake_1 == 0
%                 app.switch_left_noButton.Value = true;
%             end
%             duration_2 = 2*(distance_between_ego_B_initial - safety_distance )/(velocity_ego_target_2 + initial_velocity_ego - 2*velocity_vehicle_B);
%             can_overtake_2 = max(Tmin1,Tmin2_2) < duration_2;
%             if can_overtake_2 == 1
%                 app.switch_right_yesButton.Value=true;
%             elseif can_overtake_2 == 0
%                 app.switch_right_noButton.Value = true;
%             end
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButtonon = app.modeButtonGroup.SelectedObject;
            
        end

        % Value changed function: Velocity_Lead_VehiclemsEditField
        function Velocity_Lead_VehiclemsEditFieldValueChanged(app, event)
            value = app.Velocity_Lead_VehiclemsEditField.Value;
            assignin('base','velocity_vehicle_B' , value);
        end

        % Callback function
        function modeButtonGroupSelectionChanged(app, event)
            selectedButton = app.modeButtonGroup.SelectedObject;
            
           
        end

        % Callback function
        function modechooseButtonGroupSelectionChanged(app, event)
            selectedButton = app.modechooseButtonGroup.SelectedObject;
             if app.autonomousButton.Value == true
                set_param("Studienarbeit/manual_or_autonomous",'value',num2str(1));
              
             elseif app.manualButton.Value == true
                 set_param("Studienarbeit/manual_or_autonomous",'value',num2str(0));
                 
             end
        end

        % Callback function
        function UTurnButtonGroupSelectionChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EgoVehicleVelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.EgoVehicleVelocitymsEditFieldLabel.Text = 'Ego Vehicle Velocity (m/s)';
            app.EgoVehicleVelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EgoVehicleVelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @EgoVehicleVelocitymsEditFieldValueChanged, true);
            app.EgoVehicleVelocitymsEditField.Value = 27.778;
            app.Ego_Vehicle_lateralposition_egomEditFieldLabel = uilabel(app.UIFigure);
            app.Ego_Vehicle_lateralposition_egomEditFieldLabel.Text = 'Ego_Vehicle_lateral position_ego (m)';
            app.Ego_Vehicle_lateralposition_egomEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ego_Vehicle_lateralposition_egomEditField.ValueChangedFcn = createCallbackFcn(app, @Ego_Vehicle_lateralposition_egomEditFieldValueChanged, true);
            app.Ego_Vehicle_lateralposition_egomEditField.Value = 1.25123959431471;
            app.Ego_Vehicle_ACC_Velocity_setmsEditFieldLabel = uilabel(app.UIFigure);
            app.Ego_Vehicle_ACC_Velocity_setmsEditFieldLabel.Text = 'Ego_Vehicle_ACC_Velocity_set (m/s)';
            app.Ego_Vehicle_ACC_Velocity_setmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ego_Vehicle_ACC_Velocity_setmsEditField.ValueChangedFcn = createCallbackFcn(app, @Ego_Vehicle_ACC_Velocity_setmsEditFieldValueChanged, true);
            app.Ego_Vehicle_ACC_Velocity_setmsEditField.Value = 33;
            app.Velocity_left_lane_maxmsEditFieldLabel = uilabel(app.UIFigure);
            app.Velocity_left_lane_maxmsEditFieldLabel.Text = 'Velocity_left_lane_max (m/s)';
            app.Velocity_left_lane_maxmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocity_left_lane_maxmsEditField.ValueChangedFcn = createCallbackFcn(app, @Velocity_left_lane_maxmsEditFieldValueChanged, true);
            app.Velocity_left_lane_maxmsEditField.Value = 35;
            app.Velocity_right_lane_maxmsEditFieldLabel = uilabel(app.UIFigure);
            app.Velocity_right_lane_maxmsEditFieldLabel.Text = 'Velocity_right_lane_max (m/s)';
            app.Velocity_right_lane_maxmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocity_right_lane_maxmsEditField.ValueChangedFcn = createCallbackFcn(app, @Velocity_right_lane_maxmsEditFieldValueChanged, true);
            app.Velocity_right_lane_maxmsEditField.Value = 35;
            app.Velocity_Lead_VehiclemsEditFieldLabel = uilabel(app.UIFigure);
            app.Velocity_Lead_VehiclemsEditFieldLabel.Text = 'Velocity_Lead_Vehicle (m/s)';
            app.Velocity_Lead_VehiclemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Velocity_Lead_VehiclemsEditField.ValueChangedFcn = createCallbackFcn(app, @Velocity_Lead_VehiclemsEditFieldValueChanged, true);
            app.Velocity_Lead_VehiclemsEditField.Value = 30.556;
            app.ADSTestBenchGUILabel = uilabel(app.UIFigure);
            app.ADSTestBenchGUILabel.Text = 'ADS TestBench GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Commandd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end